classdef CSVtoTimePts < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EventFinderUIFigure            matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        EventFinderTab                 matlab.ui.container.Tab
        SelectFilesButton              matlab.ui.control.Button
        FindtimepointsButton           matlab.ui.control.Button
        readyLampLabel                 matlab.ui.control.Label
        readyLamp                      matlab.ui.control.Lamp
        MarkerFileNameEditFieldLabel   matlab.ui.control.Label
        MarkerFileNameEditField        matlab.ui.control.EditField
        SystemStartTimeEditFieldLabel  matlab.ui.control.Label
        SystemStartTimeEditField       matlab.ui.control.NumericEditField
        NumberofMarkersEditFieldLabel  matlab.ui.control.Label
        NumberofMarkersEditField       matlab.ui.control.NumericEditField
        SelectGroupDropDownLabel       matlab.ui.control.Label
        SelectGroupDropDown            matlab.ui.control.DropDown
        ParticipantCheckBox            matlab.ui.control.CheckBox
        CaregiverCheckBox              matlab.ui.control.CheckBox
        PlotGraphforLabel              matlab.ui.control.Label
        ShowMarkersforLabel            matlab.ui.control.Label
        EDACheckBox                    matlab.ui.control.CheckBox
        HRCheckBox                     matlab.ui.control.CheckBox
        TempCheckBox                   matlab.ui.control.CheckBox
        LoadDataLabel                  matlab.ui.control.Label
        SelectDirectoryButton          matlab.ui.control.Button
        SavingDirectoryLabel           matlab.ui.control.Label
        GroupThresholdTab              matlab.ui.container.Tab
        SelectGroupDropDown_2Label     matlab.ui.control.Label
        SelectGroupDropDownThreshold   matlab.ui.control.DropDown
        UpdateThresholdButton          matlab.ui.control.Button
        HeartRatebpmEditFieldLabel     matlab.ui.control.Label
        HeartRatebpmEditField          matlab.ui.control.NumericEditField
        ElectrodermalActivitychangeusEditFieldLabel  matlab.ui.control.Label
        ElectrodermalActivitychangeusEditField  matlab.ui.control.NumericEditField
        TemperaturechangedegreeEditFieldLabel  matlab.ui.control.Label
        TemperaturechangedegreeEditField  matlab.ui.control.NumericEditField
        SelectUserDropDownLabel        matlab.ui.control.Label
        SelectUserDropDown             matlab.ui.control.DropDown
        HRSFEditFieldLabel             matlab.ui.control.Label
        HRSFEditField                  matlab.ui.control.NumericEditField
        EDASFEditFieldLabel            matlab.ui.control.Label
        EDASFEditField                 matlab.ui.control.NumericEditField
        TEMPSFEditFieldLabel           matlab.ui.control.Label
        TEMPSFEditField                matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        
        % Table version of the CSV table
        % Participant
        BVP_p = table();
        HR_p = table();
        TEMP_p = table();
        EDA_p = table();
        % Care-giver
        BVP_c = table();
        HR_c = table();
        TEMP_c = table();
        EDA_c = table();
        
        % Signal quality index
        % Participant
        SQI_TEMP_p = [];
        SQI_EDA_p = [];
        
        % Care-giver
        SQI_TEMP_c = [];
        SQI_EDA_c = [];
        
        % Misc variables
        number_markers = 1;
        group = '';
        thresholds = struct();
        start_time = 0;
        is_ready = 0;
        
        % Algorithm related variables
        event_type_p = [];
        event_type_c = [];
       
        % default saving directory:
        saving_directory = erase(mfilename('fullpath'),strcat('\',mfilename));
        original_directory = erase(mfilename('fullpath'),strcat('\',mfilename));
        % tag for the folder name
        time_stamp = 0;
        
    end
    
    % Helper functions
    methods (Access = private)
        % Average the raw data and return an averaged array along with the corresponding time array.
        % Also, tag which time is a bad time that should be ignored if there happen to have event.
        % To get the bad time this function use the signal quality index calculated elsewhere in the application
        function [averaged_array,time_array,bad_time_array,averaged_sqi] = get_average(app,type_part,type,win_size,start,stop)
            
            % Setting default values for some variables
            data = [];
            time = [];
            sqi = [];
            sqi_threshold = 0;
            current_index = 1;
            arr_ind = 1;
            bad_arr_ind = 1;
            bad_time_array = [];
            averaged_sqi = [];
            
            % First populate variables with the right type
            if(strcmp(type_part,"p"))
                hr = app.HR_p;
                temp = app.TEMP_p;
                eda = app.EDA_p;
                sqi_temp = app.SQI_TEMP_p;
                sqi_eda = app.SQI_EDA_p;
            else
                hr = app.HR_c;
                temp = app.TEMP_c;
                eda = app.EDA_c;
                sqi_temp = app.SQI_TEMP_c;
                sqi_eda = app.SQI_EDA_c;
            end
            
            % Select the right time, data and sqi depending on the data type
            if(strcmp(type,'hr'))
                time = hr{1:end,1};
                data = hr{1:end,2};
            elseif(strcmp(type,'temp'))
                time = temp{1:end,1};
                data = temp{1:end,2};
                sqi = sqi_temp;
                sqi_threshold = mean(sqi)-std(sqi);
                display(['Threshold Temperature: ', num2str(sqi_threshold)]);
            elseif(strcmp(type,'eda'))
                time = eda{1:end,1};
                data = eda{1:end,2};
                sqi = sqi_eda;
                sqi_threshold = mean(sqi)-std(sqi); 
                display(['Threshold EDA: ', num2str(sqi_threshold)]);
            end
            
            
            max_index = size(time,1);
            
            % TODO comment this out and check if this part is allright
            % Averaging step:
            % Going from start time to stop time with a given window size
            % we averaged the data points that are within.
            for threshold = start:win_size:stop-win_size
                %Reset some variables and get current time
                total_size = 0;
                total_data = 0;
                total_sqi = [];
                isBadTime = 0;
                current_time = str2num(time{current_index,1});
                % While we are in that window we sum the data points together
                % if the sqi is too low for one point in this window we flag the whole thing as bad
                while(current_time < (threshold+win_size) && (current_index < max_index))
                    total_data = total_data + str2num(data{current_index,1});
                    total_size = total_size + 1;
                    if(~strcmp(type,'hr'))
                        total_sqi = [total_sqi, sqi(current_index)];    
                    end
                    
                    if(~strcmp(type,'hr') && sqi(current_index) < sqi_threshold)
                        isBadTime = 1;
                    end
                    current_index = current_index + 1;
                    current_time = str2num(time{current_index,1});
                end
                
                % If we averaged something within this window we add to the averaged
                % array and we choose the middle point to be the time stamp
                % if this was a bad sqi window we record that time stamp as being unreliable.
                if(total_size > 0)
                    averaged_data = total_data/total_size;
                    averaged_array(arr_ind,1) = averaged_data;
                    time_array(arr_ind,1) = threshold+(win_size/2);
                    if(~strcmp(type,'hr'))
                        averaged_sqi(arr_ind) = min(total_sqi);
                    end
                    if(isBadTime)
                        bad_time_array(bad_arr_ind,1) = time_array(arr_ind,1);
                        bad_arr_ind = bad_arr_ind + 1;
                    end
                    arr_ind = arr_ind + 1;
                end
            end
            
        end

        function populate_group_threshold(app,selected_group,selected_user)
            % Loading the Group structure
            Group = load('Group.mat');
            Group = Group.Group;
            
            % Changing the right field in the Group structure
            if(strcmp(selected_group,'Blue'))
                if(strcmp(selected_user,'Participant'))
                    app.HeartRatebpmEditField.Value = Group.blue.p.hr;
                    app.ElectrodermalActivitychangeusEditField.Value = Group.blue.p.eda;
                    app.TemperaturechangedegreeEditField.Value = Group.blue.p.temp;
                    app.HRSFEditField.Value = Group.blue.p.hr_sf;
                    app.EDASFEditField.Value = Group.blue.p.eda_sf;
                    app.TEMPSFEditField.Value = Group.blue.p.temp_sf;
                elseif(strcmp(selected_user,'Care-giver'))
                    app.HeartRatebpmEditField.Value = Group.blue.c.hr;
                    app.ElectrodermalActivitychangeusEditField.Value = Group.blue.c.eda;
                    app.TemperaturechangedegreeEditField.Value = Group.blue.c.temp;
                    app.HRSFEditField.Value = Group.blue.c.hr_sf;
                    app.EDASFEditField.Value = Group.blue.c.eda_sf;
                    app.TEMPSFEditField.Value = Group.blue.c.temp_sf;                   
                end
            elseif(strcmp(selected_group,'Green'))
                if(strcmp(selected_user,'Participant'))
                    app.HeartRatebpmEditField.Value = Group.green.p.hr;
                    app.ElectrodermalActivitychangeusEditField.Value = Group.green.p.eda;
                    app.TemperaturechangedegreeEditField.Value = Group.green.p.temp;
                    app.HRSFEditField.Value = Group.green.p.hr_sf;
                    app.EDASFEditField.Value = Group.green.p.eda_sf;
                    app.TEMPSFEditField.Value = Group.green.p.temp_sf;                   
                elseif(strcmp(selected_user,'Care-giver'))
                    app.HeartRatebpmEditField.Value = Group.green.c.hr;
                    app.ElectrodermalActivitychangeusEditField.Value = Group.green.c.eda;
                    app.TemperaturechangedegreeEditField.Value = Group.green.c.temp;
                    app.HRSFEditField.Value = Group.green.c.hr_sf;
                    app.EDASFEditField.Value = Group.green.c.eda_sf;
                    app.TEMPSFEditField.Value = Group.green.c.temp_sf;                 
                end            
            elseif(strcmp(selected_group,'Orange'))
                if(strcmp(selected_user,'Participant'))
                    app.HeartRatebpmEditField.Value = Group.orange.p.hr;
                    app.ElectrodermalActivitychangeusEditField.Value = Group.orange.p.eda;
                    app.TemperaturechangedegreeEditField.Value = Group.orange.p.temp;
                    app.HRSFEditField.Value = Group.orange.p.hr_sf;
                    app.EDASFEditField.Value = Group.orange.p.eda_sf;
                    app.TEMPSFEditField.Value = Group.orange.p.temp_sf;                 
                elseif(strcmp(selected_user,'Care-giver'))
                    app.HeartRatebpmEditField.Value = Group.orange.c.hr;
                    app.ElectrodermalActivitychangeusEditField.Value = Group.orange.c.eda;
                    app.TemperaturechangedegreeEditField.Value = Group.orange.c.temp;
                    app.HRSFEditField.Value = Group.orange.c.hr_sf;
                    app.EDASFEditField.Value = Group.orange.c.eda_sf;
                    app.TEMPSFEditField.Value = Group.orange.c.temp_sf;                    
                end                
            elseif(strcmp(selected_group,'Red'))
                if(strcmp(selected_user,'Participant'))
                    app.HeartRatebpmEditField.Value = Group.red.p.hr;
                    app.ElectrodermalActivitychangeusEditField.Value = Group.red.p.eda;
                    app.TemperaturechangedegreeEditField.Value = Group.red.p.temp;
                    app.HRSFEditField.Value = Group.red.p.hr_sf;
                    app.EDASFEditField.Value = Group.red.p.eda_sf;
                    app.TEMPSFEditField.Value = Group.red.p.temp_sf;                   
                elseif(strcmp(selected_user,'Care-giver'))
                    app.HeartRatebpmEditField.Value = Group.red.c.hr;
                    app.ElectrodermalActivitychangeusEditField.Value = Group.red.c.eda;
                    app.TemperaturechangedegreeEditField.Value = Group.red.c.temp;
                    app.HRSFEditField.Value = Group.red.c.hr_sf;
                    app.EDASFEditField.Value = Group.red.c.eda_sf;
                    app.TEMPSFEditField.Value = Group.red.c.temp_sf;                   
                end             
            elseif(strcmp(selected_group,'Yellow'))
                if(strcmp(selected_user,'Participant'))
                    app.HeartRatebpmEditField.Value = Group.yellow.p.hr;
                    app.ElectrodermalActivitychangeusEditField.Value = Group.yellow.p.eda;
                    app.TemperaturechangedegreeEditField.Value = Group.yellow.p.temp;
                    app.HRSFEditField.Value = Group.yellow.p.hr_sf;
                    app.EDASFEditField.Value = Group.yellow.p.eda_sf;
                    app.TEMPSFEditField.Value = Group.yellow.p.temp_sf;                 
                elseif(strcmp(selected_user,'Care-giver'))
                    app.HeartRatebpmEditField.Value = Group.yellow.c.hr;
                    app.ElectrodermalActivitychangeusEditField.Value = Group.yellow.c.eda;
                    app.TemperaturechangedegreeEditField.Value = Group.yellow.c.temp;
                    app.HRSFEditField.Value = Group.yellow.c.hr_sf;
                    app.EDASFEditField.Value = Group.yellow.c.eda_sf;
                    app.TEMPSFEditField.Value = Group.yellow.c.temp_sf;                    
                end              
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Group Threshold default values = Blue,Participant
            populate_group_threshold(app,'Blue','Participant');
        end

        % Button pushed function: SelectFilesButton
        function SelectFilesButtonPushed(app, event)
            % When the select files button is pressed we choose a
            % folder and we extract the data from the .CSV data files to
            % put them into the variables
            
            path = uigetdir;
            if isequal(path,0)
                disp('User selected Cancel');
                return;
            else
                disp(['User selected folder: ', path]);
                listing = dir(path);
                row_offset = 1;
                col_offset = 0;
                for i = 1:size(listing)
                    % We go through each files and we read the CSV into
                    % a table that we save in the appropriate file.
                    file_name = listing(i).name;
                    full_path = strcat(path,'\',file_name);
                    if(contains(file_name, 'p_bvp'))
                        disp(['File : ',file_name,' is bvp p']);
                        app.BVP_p =readtable(full_path);
                    elseif(contains(file_name, 'p_hr'))
                        disp(['File : ',file_name,' is HR p']);
                        app.HR_p =readtable(full_path);
                    elseif(contains(file_name, 'p_temp'))
                        disp(['File : ',file_name,' is TEMP p']);
                        app.TEMP_p =readtable(full_path);
                    elseif(contains(file_name, 'p_eda'))
                        disp(['File : ',file_name,' is EDA p']);
                        app.EDA_p =readtable(full_path);
                    elseif(contains(file_name, 'c_bvp'))
                        disp(['File : ',file_name,' is bvp c']);
                        app.BVP_c =readtable(full_path);
                    elseif(contains(file_name, 'c_hr'))
                        disp(['File : ',file_name,' is HR c']);
                        app.HR_c =readtable(full_path);
                    elseif(contains(file_name, 'c_temp'))
                        disp(['File : ',file_name,' is TEMP c']);
                        app.TEMP_c =readtable(full_path);
                    elseif(contains(file_name, 'c_eda'))
                        disp(['File : ',file_name,' is EDA c']);
                        app.EDA_c =readtable(full_path);
                    end
                    
                    app.readyLamp.Color = [0,1,0];
                    app.is_ready = 1;
                end
            end
        end

        % Button pushed function: FindtimepointsButton
        function FindtimepointsButtonPushed(app, event)
            display(app.saving_directory)
            
            % Get the time_stamp for saving purposes
            app.time_stamp = floor(now*100000);
            
            % When the find time points button is pushed we load 
            % all the necessary variables before calling the find events function
            app.start_time = app.SystemStartTimeEditField.Value;
            if(app.is_ready && app.start_time > 0)
                clc;
                display('Program Launched');
                display('Loading variables...');
                app.number_markers = app.NumberofMarkersEditField.Value;
                app.group = app.SelectGroupDropDown.Value;
                temp_data = load('Group.mat');
                
                % Here we load the thresholds for the group we are trying to do
                if(strcmp(app.group,'Blue'))
                    app.thresholds = temp_data.Group.blue;
                elseif(strcmp(app.group,'Green'))
                    app.thresholds = temp_data.Group.green;
                elseif(strcmp(app.group,'Orange'))
                    app.thresholds = temp_data.Group.orange;
                elseif(strcmp(app.group,'Red'))
                    app.thresholds = temp_data.Group.red;
                elseif(strcmp(app.group,'Yellow'))
                    app.thresholds = temp_data.Group.yellow;
                end
                
                display('Calculating signal quality for participants...')
                [bvp_struct.p,sc_struct.p,temp_struct.p] = get_sqi(app,"p");
                display('Saving signal quality for participants...');
                save_sqi(app,"p",bvp_struct.p.score,sc_struct.p.score,temp_struct.p.score);
                display('Calculating signal quality for care-giver...')
                [bvp_struct.c,sc_struct.c,temp_struct.c] = get_sqi(app,"c");
                display('Saving signal quality for care-giver...');
                save_sqi(app,"c",bvp_struct.c.score,sc_struct.c.score,temp_struct.c.score);
                
                display('Overwrite raw data with filtered signal for EDA and TEMP...');
                
                test = calc_hrv_features(app,"p",10);
                assignin('base','test',test);
                
                % Convert cell to 'table'
                temp_struct.p.filt = cellstr(num2str(temp_struct.p.filt));
                temp_struct.c.filt = cellstr(num2str(temp_struct.c.filt));
                sc_struct.p.filt = cellstr(num2str(sc_struct.p.filt));
                sc_struct.c.filt = cellstr(num2str(sc_struct.c.filt)); 
                
                app.TEMP_p(:,2) = temp_struct.p.filt;
                app.TEMP_c(:,2) = temp_struct.c.filt;
                app.EDA_p(:,2) = sc_struct.p.filt;
                app.EDA_c(:,2) = sc_struct.c.filt;                   
                
                display('Finding events for participants...');
                app.SQI_TEMP_p = temp_struct.p.sqi;
                app.SQI_EDA_p = sc_struct.p.sqi;
                app.SQI_TEMP_c = temp_struct.c.sqi;
                app.SQI_EDA_c = sc_struct.c.sqi;
                
                [event_struct.p,hr_struct.p,temp_struct.p,sc_struct.p] = find_events(app,"p");
                display('Finding events for care-giver...');
                [event_struct.c,hr_struct.c,temp_struct.c,sc_struct.c] = find_events(app,"c");
                display('Save events for participants...');
                save_events(app,event_struct.p.events,"p");
                display('Save events for care-giver...');
                save_events(app,event_struct.c.events,"c");
                
                if(app.ParticipantCheckBox.Value == 1)
                    display('Plotting Participant...');
                    plot_data_markers('Participant',app.start_time,app.HR_p,sc_struct.p.time,sc_struct.p.avg,sc_struct.p.avg_sqi,...
                    hr_struct.p.time,hr_struct.p.avg,temp_struct.p.time,temp_struct.p.avg,temp_struct.p.avg_sqi,event_struct.p.events,event_struct.p.type,'r');
                end
                
                if(app.CaregiverCheckBox.Value == 1)
                    display('Plotting Care-giver...');
                    plot_data_markers('Caregiver',app.start_time,app.HR_c,sc_struct.c.time,sc_struct.c.avg,sc_struct.c.avg_sqi,...
                    hr_struct.c.time,hr_struct.c.avg,temp_struct.c.time,temp_struct.c.avg,temp_struct.c.avg_sqi,event_struct.c.events,event_struct.c.type,'b');
                end
                display('Program Terminated!');
            else
                display('Select a folder first and make sure start time > 0');
            end
            
        end

        % Button pushed function: UpdateThresholdButton
        function UpdateThresholdButtonPushed(app, event)
            % Collecting the threshold from the input fields
            HR_bpm = app.HeartRatebpmEditField.Value;
            EDA_change = app.ElectrodermalActivitychangeusEditField.Value;
            TEMP_change = app.TemperaturechangedegreeEditField.Value;
            HR_SF = app.HRSFEditField.Value;
            EDA_SF = app.EDASFEditField.Value;
            TEMP_SF = app.TEMPSFEditField.Value;
            selected_group = app.SelectGroupDropDownThreshold.Value;
            selected_user = app.SelectUserDropDown.Value;
            
            % Loading the Group structure
            Group = load('Group.mat');
            Group = Group.Group;
            
            % Changing the right field in the Group structure
            if(strcmp(selected_group,'Blue'))
                if(strcmp(selected_user,'Participant'))
                    Group.blue.p.eda = EDA_change;
                    Group.blue.p.hr = HR_bpm;
                    Group.blue.p.temp = TEMP_change;
                    Group.blue.p.hr_sf = HR_SF;
                    Group.blue.p.eda_sf = EDA_SF;
                    Group.blue.p.temp_sf = TEMP_SF;
                elseif(strcmp(selected_user,'Care-giver'))
                    Group.blue.c.eda = EDA_change;
                    Group.blue.c.hr = HR_bpm;
                    Group.blue.c.temp = TEMP_change; 
                    Group.blue.c.hr_sf = HR_SF;
                    Group.blue.c.eda_sf = EDA_SF;
                    Group.blue.c.temp_sf = TEMP_SF;                    
                end
            elseif(strcmp(selected_group,'Green'))
                if(strcmp(selected_user,'Participant'))
                    Group.green.p.eda = EDA_change;
                    Group.green.p.hr = HR_bpm;
                    Group.green.p.temp = TEMP_change;
                    Group.green.p.hr_sf = HR_SF;
                    Group.green.p.eda_sf = EDA_SF;
                    Group.green.p.temp_sf = TEMP_SF;                    
                elseif(strcmp(selected_user,'Care-giver'))
                    Group.green.c.eda = EDA_change;
                    Group.green.c.hr = HR_bpm;
                    Group.green.c.temp = TEMP_change;
                    Group.green.c.hr_sf = HR_SF;
                    Group.green.c.eda_sf = EDA_SF;
                    Group.green.c.temp_sf = TEMP_SF;                    
                end            
            elseif(strcmp(selected_group,'Orange'))
                if(strcmp(selected_user,'Participant'))
                    Group.orange.p.eda = EDA_change;
                    Group.orange.p.hr = HR_bpm;
                    Group.orange.p.temp = TEMP_change;
                    Group.orange.p.hr_sf = HR_SF;
                    Group.orange.p.eda_sf = EDA_SF;
                    Group.orange.p.temp_sf = TEMP_SF;                    
                elseif(strcmp(selected_user,'Care-giver'))
                    Group.orange.c.eda = EDA_change;
                    Group.orange.c.hr = HR_bpm;
                    Group.orange.c.temp = TEMP_change; 
                    Group.orange.c.hr_sf = HR_SF;
                    Group.orange.c.eda_sf = EDA_SF;
                    Group.orange.c.temp_sf = TEMP_SF;                    
                end                
            elseif(strcmp(selected_group,'Red'))
                if(strcmp(selected_user,'Participant'))
                    Group.red.p.eda = EDA_change;
                    Group.red.p.hr = HR_bpm;
                    Group.red.p.temp = TEMP_change;
                    Group.red.p.hr_sf = HR_SF;
                    Group.red.p.eda_sf = EDA_SF;
                    Group.red.p.temp_sf = TEMP_SF;                    
                elseif(strcmp(selected_user,'Care-giver'))
                    Group.red.c.eda = EDA_change;
                    Group.red.c.hr = HR_bpm;
                    Group.red.c.temp = TEMP_change;
                    Group.red.c.hr_sf = HR_SF;
                    Group.red.c.eda_sf = EDA_SF;
                    Group.red.c.temp_sf = TEMP_SF;                    
                end             
            elseif(strcmp(selected_group,'Yellow'))
                if(strcmp(selected_user,'Participant'))
                    Group.yellow.p.eda = EDA_change;
                    Group.yellow.p.hr = HR_bpm;
                    Group.yellow.p.temp = TEMP_change;
                    Group.yellow.p.hr_sf = HR_SF;
                    Group.yellow.p.eda_sf = EDA_SF;
                    Group.yellow.p.temp_sf = TEMP_SF;                    
                elseif(strcmp(selected_user,'Care-giver'))
                    Group.yellow.c.eda = EDA_change;
                    Group.yellow.c.hr = HR_bpm;
                    Group.yellow.c.temp = TEMP_change;  
                    Group.yellow.c.hr_sf = HR_SF;
                    Group.yellow.c.eda_sf = EDA_SF;
                    Group.yellow.c.temp_sf = TEMP_SF;                    
                end              
            end
            
            % Saving the Group data
            filename = strcat(app.original_directory,'/Group.mat');
            save(filename,'Group');
            display('Updated the Group threshold!');
        end

        % Value changed function: SelectUserDropDown
        function SelectUserDropDownValueChanged(app, event)
            sel_user = app.SelectUserDropDown.Value;
            sel_group = app.SelectGroupDropDownThreshold.Value;
            populate_group_threshold(app,sel_group,sel_user);
        end

        % Value changed function: SelectGroupDropDownThreshold
        function SelectGroupDropDownThresholdValueChanged(app, event)
            sel_user = app.SelectUserDropDown.Value;
            sel_group = app.SelectGroupDropDownThreshold.Value;
            populate_group_threshold(app,sel_group,sel_user);
        end

        % Button pushed function: SelectDirectoryButton
        function SelectDirectoryButtonPushed(app, event)
            path = uigetdir;
            if(path ~= 0)
               app.saving_directory = path;
               disp(['updated saving directory to : ',path]);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EventFinderUIFigure = uifigure;
            app.EventFinderUIFigure.Name = 'Event Finder';
            app.FileMenu = uimenu(app.EventFinderUIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Text = 'Exit';
            app.TabGroup = uitabgroup(app.EventFinderUIFigure);
            app.EventFinderTab = uitab(app.TabGroup);
            app.EventFinderTab.Title = 'Event Finder';
            app.SelectFilesButton = uibutton(app.EventFinderTab, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesButtonPushed, true);
            app.SelectFilesButton.Text = 'Select Files';
            app.FindtimepointsButton = uibutton(app.EventFinderTab, 'push');
            app.FindtimepointsButton.ButtonPushedFcn = createCallbackFcn(app, @FindtimepointsButtonPushed, true);
            app.FindtimepointsButton.Text = 'Find time points';
            app.readyLampLabel = uilabel(app.EventFinderTab);
            app.readyLampLabel.Text = 'ready';
            app.readyLamp = uilamp(app.EventFinderTab);
            app.MarkerFileNameEditFieldLabel = uilabel(app.EventFinderTab);
            app.MarkerFileNameEditFieldLabel.Text = 'Marker File Name';
            app.MarkerFileNameEditField = uieditfield(app.EventFinderTab, 'text');
            app.SystemStartTimeEditFieldLabel = uilabel(app.EventFinderTab);
            app.SystemStartTimeEditFieldLabel.Text = 'System Start Time';
            app.SystemStartTimeEditField = uieditfield(app.EventFinderTab, 'numeric');
            app.SystemStartTimeEditField.Limits = [0 Inf];
            app.NumberofMarkersEditFieldLabel = uilabel(app.EventFinderTab);
            app.NumberofMarkersEditFieldLabel.Text = 'Number of Markers';
            app.NumberofMarkersEditField = uieditfield(app.EventFinderTab, 'numeric');
            app.NumberofMarkersEditField.Limits = [1 Inf];
            app.NumberofMarkersEditField.Value = 1;
            app.SelectGroupDropDownLabel = uilabel(app.EventFinderTab);
            app.SelectGroupDropDownLabel.Text = 'Select Group';
            app.SelectGroupDropDown = uidropdown(app.EventFinderTab);
            app.SelectGroupDropDown.Items = {'Blue', 'Green', 'Orange', 'Red', 'Yellow'};
            app.SelectGroupDropDown.Value = 'Blue';
            app.ParticipantCheckBox = uicheckbox(app.EventFinderTab);
            app.ParticipantCheckBox.Text = 'Participant';
            app.CaregiverCheckBox = uicheckbox(app.EventFinderTab);
            app.CaregiverCheckBox.Text = 'Caregiver';
            app.PlotGraphforLabel = uilabel(app.EventFinderTab);
            app.PlotGraphforLabel.Text = 'Plot Graph for';
            app.ShowMarkersforLabel = uilabel(app.EventFinderTab);
            app.ShowMarkersforLabel.Text = 'Show Markers for';
            app.EDACheckBox = uicheckbox(app.EventFinderTab);
            app.EDACheckBox.Text = 'EDA';
            app.HRCheckBox = uicheckbox(app.EventFinderTab);
            app.HRCheckBox.Text = 'HR';
            app.TempCheckBox = uicheckbox(app.EventFinderTab);
            app.TempCheckBox.Text = 'Temp';
            app.LoadDataLabel = uilabel(app.EventFinderTab);
            app.LoadDataLabel.Text = 'Load Data';
            app.SelectDirectoryButton = uibutton(app.EventFinderTab, 'push');
            app.SelectDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirectoryButtonPushed, true);
            app.SelectDirectoryButton.Text = 'Select Directory';
            app.SavingDirectoryLabel = uilabel(app.EventFinderTab);
            app.SavingDirectoryLabel.Text = 'Saving Directory';
            app.GroupThresholdTab = uitab(app.TabGroup);
            app.GroupThresholdTab.Title = 'Group Threshold';
            app.SelectGroupDropDown_2Label = uilabel(app.GroupThresholdTab);
            app.SelectGroupDropDown_2Label.Text = 'Select Group';
            app.SelectGroupDropDownThreshold = uidropdown(app.GroupThresholdTab);
            app.SelectGroupDropDownThreshold.Items = {'Blue', 'Green', 'Orange', 'Red', 'Yellow'};
            app.SelectGroupDropDownThreshold.ValueChangedFcn = createCallbackFcn(app, @SelectGroupDropDownThresholdValueChanged, true);
            app.SelectGroupDropDownThreshold.Value = 'Blue';
            app.UpdateThresholdButton = uibutton(app.GroupThresholdTab, 'push');
            app.UpdateThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateThresholdButtonPushed, true);
            app.UpdateThresholdButton.Text = 'Update Threshold';
            app.HeartRatebpmEditFieldLabel = uilabel(app.GroupThresholdTab);
            app.HeartRatebpmEditFieldLabel.Text = 'Heart Rate (bpm)';
            app.HeartRatebpmEditField = uieditfield(app.GroupThresholdTab, 'numeric');
            app.HeartRatebpmEditField.Limits = [0 Inf];
            app.HeartRatebpmEditField.Value = 10;
            app.ElectrodermalActivitychangeusEditFieldLabel = uilabel(app.GroupThresholdTab);
            app.ElectrodermalActivitychangeusEditFieldLabel.Text = 'Electrodermal Activity change (us)';
            app.ElectrodermalActivitychangeusEditField = uieditfield(app.GroupThresholdTab, 'numeric');
            app.ElectrodermalActivitychangeusEditField.Limits = [0 Inf];
            app.ElectrodermalActivitychangeusEditField.Value = 0.1;
            app.TemperaturechangedegreeEditFieldLabel = uilabel(app.GroupThresholdTab);
            app.TemperaturechangedegreeEditFieldLabel.Text = 'Temperature change (degree)';
            app.TemperaturechangedegreeEditField = uieditfield(app.GroupThresholdTab, 'numeric');
            app.TemperaturechangedegreeEditField.Limits = [0 Inf];
            app.TemperaturechangedegreeEditField.Value = 0.1;
            app.SelectUserDropDownLabel = uilabel(app.GroupThresholdTab);
            app.SelectUserDropDownLabel.Text = 'Select User';
            app.SelectUserDropDown = uidropdown(app.GroupThresholdTab);
            app.SelectUserDropDown.Items = {'Participant', 'Care-giver'};
            app.SelectUserDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectUserDropDownValueChanged, true);
            app.SelectUserDropDown.Value = 'Participant';
            app.HRSFEditFieldLabel = uilabel(app.GroupThresholdTab);
            app.HRSFEditFieldLabel.Text = 'HR SF';
            app.HRSFEditField = uieditfield(app.GroupThresholdTab, 'numeric');
            app.HRSFEditField.Limits = [0 Inf];
            app.EDASFEditFieldLabel = uilabel(app.GroupThresholdTab);
            app.EDASFEditFieldLabel.Text = 'EDA SF';
            app.EDASFEditField = uieditfield(app.GroupThresholdTab, 'numeric');
            app.EDASFEditField.Limits = [0 Inf];
            app.TEMPSFEditFieldLabel = uilabel(app.GroupThresholdTab);
            app.TEMPSFEditFieldLabel.Text = 'TEMP SF';
            app.TEMPSFEditField = uieditfield(app.GroupThresholdTab, 'numeric');
            app.TEMPSFEditField.Limits = [0 Inf];
        end
    end

    methods (Access = public)

        % Construct app
        function app = CSVtoTimePts

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EventFinderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EventFinderUIFigure)
        end
    end
end